classdef Braccio_Arnold_CamApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BraccioArnoldRobotControllerUIFigure  matlab.ui.Figure
        generatefromcameraButton  matlab.ui.control.Button
        q4EditField               matlab.ui.control.NumericEditField
        q4EditFieldLabel          matlab.ui.control.Label
        q5EditField               matlab.ui.control.NumericEditField
        q5EditFieldLabel          matlab.ui.control.Label
        q3EditField               matlab.ui.control.NumericEditField
        q3EditFieldLabel          matlab.ui.control.Label
        q2EditField               matlab.ui.control.NumericEditField
        q2EditFieldLabel          matlab.ui.control.Label
        q1EditField               matlab.ui.control.NumericEditField
        q1EditFieldLabel          matlab.ui.control.Label
        yEditField                matlab.ui.control.NumericEditField
        yEditFieldLabel           matlab.ui.control.Label
        xEditField                matlab.ui.control.NumericEditField
        xEditFieldLabel           matlab.ui.control.Label
        DisplayvaluesButton       matlab.ui.control.Button
    end

    
    properties (Access = public)
         xx = 0
         yy = 0
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            value = app.xEditField.Value;
            
        end

        % Button pushed function: DisplayvaluesButton
        function DisplayvaluesButtonPushed(app, event)

% This file is designed to solve the inverse kinematics of the Braccio and
% get the joint variables theta 1 to 5. 
% x(1): theta 1, x(2): theta 2, x(3): theta 3, x(4): theta 4, x(5): theta 5

% X0 = [0 -pi/2 0 -pi/2 0];     % initial value for x(i)
X0 = [0 -pi/2 0 0 0];     % initial value for x(i)
OPTIONS = optimoptions('fsolve','Algorithm','trust-region');
% [x1,FVAL,EXITFLAG] = fsolve(@Braccio_invK, X0, OPTIONS)
LB = (pi/180)*[-90 -165 -90 -180 -90];
UB = (pi/180)*[90 -15 90 0 90];
serialports = serialportlist("available");
s = serialport(serialports(2),9600);
pause(2);

% startup_rvc

function F = Braccio_invK(x)
d1 = 74.71;             % 77.71 + 2mm woodenbase thickness
a2 = 125.2;
a3 = 125.2;
d5 = 190;
px = app.xEditField.Value;          % px range is (min [-150 150], max [-400 , 400])
py = app.yEditField.Value;             % py range is (min [-150 150], max [-400 , 400])
pz = 10;           % pz range is [ 0, 480]   Three position components should satisfy the sphere equation X^2+Y^2+Z^2=r^2           
x5z0 = cosd(45);
y5z0 = cosd(90);
z5z0 = cosd(135);
% z5z0 = 0;
 F(1) = -d5*cos(x(1))*sin(x(2)+x(3)+x(4)) + cos(x(1))*(a3*cos(x(2)+x(3))+a2*cos(x(2))) - px;
 F(2) = -d5*sin(x(1))*sin(x(2)+x(3)+x(4)) + sin(x(1))*(a3*cos(x(2)+x(3))+a2*cos(x(2))) - py;
 F(3) = -d5*cos(x(2)+x(3)+x(4)) - a3*sin(x(2)+x(3)) - a2*sin(x(2)) + d1 - pz;
 F(4) = -cos(x(2)+x(3)+x(4)) - z5z0;
 F(5) =  x(5) + atan2(y5z0,x5z0);
end

            %img = snapshot(webcam(1));
            %f1 = figure();
            %im = image(0, 0, img);
            %x=0
            %set(im, 'ButtonDownFcn', @click);
            
                %img = snapshot(webcam(1));
                %im = image(0, 0, img);
                %[x,y]=ginput(1);
                %x = x /22;
               [x,EXITFLAG] = lsqnonlin(@Braccio_invK,X0,LB,UB,OPTIONS)
               
              theta = x*180/pi
              
              b = theta;
   pause(10);
               app.q1EditField.Value= b(1);
               app.q2EditField.Value= b(2);
               app.q3EditField.Value= b(3);
               app.q4EditField.Value= b(4);
               app.q5EditField.Value= b(5);
               
               app.q1EditField.Value = round(app.q1EditField.Value);
               app.q2EditField.Value= round(app.q2EditField.Value);
               app.q3EditField.Value= round(app.q3EditField.Value);
               app.q4EditField.Value= round(app.q4EditField.Value);
               app.q5EditField.Value= round(app.q5EditField.Value);
               
                b(1) = app.q1EditField.Value
                b(2) = app.q2EditField.Value
                b(3) = app.q3EditField.Value
                b(4) = app.q4EditField.Value
                b(5) = app.q5EditField.Value
                
                tt = "";
               for i = 1:5
                if b(i) >=0 && b(i)<10
                  tt = tt +'$$$';
                  tt = tt + num2str(b(i));
                elseif b(i) >=10 && b(i)<100
                  tt = tt +'$$';
                  tt = tt + num2str(b(i));
                elseif b(i) >=100 && b(i)<1000
                  tt = tt +'$';
                  tt = tt + num2str(b(i));
                     elseif b(i) <0 && b(i)>-10
                  tt = tt +'$$';
                  tt = tt + num2str(b(i));
                elseif b(i) <=-10 && b(i)>-100
                  tt = tt +'$';
                  tt = tt + num2str(b(i));
                elseif b(i) <=-100
                        tt = tt + num2str(b(i));
                end  
               end                     
               writeline(s,tt)
               
        end

        % Button pushed function: generatefromcameraButton
        function generatefromcameraButtonPushed(app, event)
            
% This file is designed to solve the inverse kinematics of the Braccio and
% get the joint variables theta 1 to 5.
% x(1): theta 1, x(2): theta 2, x(3): theta 3, x(4): theta 4, x(5): theta 5

% X0 = [0 -pi/2 0 -pi/2 0];     % initial value for x(i)
X0 = [0 -pi/2 0 0 0];     % initial value for x(i)
OPTIONS = optimoptions('fsolve','Algorithm','trust-region');
% [x1,FVAL,EXITFLAG] = fsolve(@Braccio_invK, X0, OPTIONS)
LB = (pi/180)*[-90 -165 -90 -180 -90];
UB = (pi/180)*[90 -15 90 0 90];
serialports = serialportlist("available");
s = serialport(serialports(2),9600);
pause(2);

% startup_rvc

 img = snapshot(webcam(1));
            f1 = figure();
            im = image(0, 0, img);

            set(im, 'ButtonDownFcn', @click);
            
                img = snapshot(webcam(1));
                im = image(0, 0, img);
                [app.xx,app.yy]=ginput(1);
                close(f1);
                
app.xx = (1000 - app.xx)/1.75;
app.yy = (app.yy- 440)/1.895;

function F = Braccio_invK(x)
d1 = 74.71;             % 77.71 + 2mm woodenbase thickness
a2 = 125.2;
a3 = 125.2;
d5 = 190;
px = app.xx;          % px range is (min [-150 150], max [-400 , 400])
py = app.yy;             % py range is (min [-150 150], max [-400 , 400])
pz = 10;           % pz range is [ 0, 480]   Three position components should satisfy the sphere equation X^2+Y^2+Z^2=r^2           
x5z0 = cosd(45);
y5z0 = cosd(90);
z5z0 = cosd(135);
% z5z0 = 0;
 F(1) = -d5*cos(x(1))*sin(x(2)+x(3)+x(4)) + cos(x(1))*(a3*cos(x(2)+x(3))+a2*cos(x(2))) - px;
 F(2) = -d5*sin(x(1))*sin(x(2)+x(3)+x(4)) + sin(x(1))*(a3*cos(x(2)+x(3))+a2*cos(x(2))) - py;
 F(3) = -d5*cos(x(2)+x(3)+x(4)) - a3*sin(x(2)+x(3)) - a2*sin(x(2)) + d1 - pz;
 F(4) = -cos(x(2)+x(3)+x(4)) - z5z0;
 F(5) =  x(5) + atan2(y5z0,x5z0);
end


               [x,EXITFLAG] = lsqnonlin(@Braccio_invK,X0,LB,UB,OPTIONS)
               
              theta = x*180/pi
              
              b = theta;
   pause(10);
               app.q1EditField.Value= b(1);
               app.q2EditField.Value= b(2);
               app.q3EditField.Value= b(3);
               app.q4EditField.Value= b(4);
               app.q5EditField.Value= b(5);
               app.xEditField.Value = app.xx;
               app.yEditField.Value = app.yy;
               app.q1EditField.Value = round(app.q1EditField.Value);
               app.q2EditField.Value= round(app.q2EditField.Value);
               app.q3EditField.Value= round(app.q3EditField.Value);
               app.q4EditField.Value= round(app.q4EditField.Value);
               app.q5EditField.Value= round(app.q5EditField.Value);
               
                b(1) = app.q1EditField.Value
                b(2) = app.q2EditField.Value
                b(3) = app.q3EditField.Value
                b(4) = app.q4EditField.Value
                b(5) = app.q5EditField.Value


                tt = "";
               for i = 1:5
                if b(i) >=0 && b(i)<10
                  tt = tt +'$$$';
                  tt = tt + num2str(b(i));
                elseif b(i) >=10 && b(i)<100
                  tt = tt +'$$';
                  tt = tt + num2str(b(i));
                elseif b(i) >=100 && b(i)<1000
                  tt = tt +'$';
                  tt = tt + num2str(b(i));
                     elseif b(i) <0 && b(i)>-10
                  tt = tt +'$$';
                  tt = tt + num2str(b(i));
                elseif b(i) <=-10 && b(i)>-100
                  tt = tt +'$';
                  tt = tt + num2str(b(i));
                elseif b(i) <=-100
                        tt = tt + num2str(b(i));
                end  
               end                     
               writeline(s,tt)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BraccioArnoldRobotControllerUIFigure = uifigure('Visible', 'off');
            app.BraccioArnoldRobotControllerUIFigure.Name = 'Braccio-Arnold Robot Controller';
            app.DisplayvaluesButton = uibutton(app.BraccioArnoldRobotControllerUIFigure, 'push');
            app.DisplayvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayvaluesButtonPushed, true);
            app.DisplayvaluesButton.Text = 'Display values';
            app.xEditFieldLabel = uilabel(app.BraccioArnoldRobotControllerUIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.BraccioArnoldRobotControllerUIFigure, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.xEditField.Value = 330;
            app.yEditFieldLabel = uilabel(app.BraccioArnoldRobotControllerUIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.BraccioArnoldRobotControllerUIFigure, 'numeric');
            app.yEditField.Value = 30;
            app.q1EditFieldLabel = uilabel(app.BraccioArnoldRobotControllerUIFigure);
            app.q1EditFieldLabel.Text = 'q1';
            app.q1EditField = uieditfield(app.BraccioArnoldRobotControllerUIFigure, 'numeric');
            app.q2EditFieldLabel = uilabel(app.BraccioArnoldRobotControllerUIFigure);
            app.q2EditFieldLabel.Text = 'q2';
            app.q2EditField = uieditfield(app.BraccioArnoldRobotControllerUIFigure, 'numeric');
            app.q3EditFieldLabel = uilabel(app.BraccioArnoldRobotControllerUIFigure);
            app.q3EditFieldLabel.Text = 'q3';
            app.q3EditField = uieditfield(app.BraccioArnoldRobotControllerUIFigure, 'numeric');
            app.q5EditFieldLabel = uilabel(app.BraccioArnoldRobotControllerUIFigure);
            app.q5EditFieldLabel.Text = 'q5';
            app.q5EditField = uieditfield(app.BraccioArnoldRobotControllerUIFigure, 'numeric');
            app.q4EditFieldLabel = uilabel(app.BraccioArnoldRobotControllerUIFigure);
            app.q4EditFieldLabel.Text = 'q4';
            app.q4EditField = uieditfield(app.BraccioArnoldRobotControllerUIFigure, 'numeric');
            app.generatefromcameraButton = uibutton(app.BraccioArnoldRobotControllerUIFigure, 'push');
            app.generatefromcameraButton.ButtonPushedFcn = createCallbackFcn(app, @generatefromcameraButtonPushed, true);
            app.generatefromcameraButton.Text = 'generate from camera';
            % Show the figure after all components are created
            app.BraccioArnoldRobotControllerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Braccio_Arnold_CamApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BraccioArnoldRobotControllerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BraccioArnoldRobotControllerUIFigure)
        end
    end
end